classdef Fitting_MTPA_Coefficient2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FittingMTPACoefficientUIFigure  matlab.ui.Figure
        LdEditFieldLabel                matlab.ui.control.Label
        LdEditField                     matlab.ui.control.NumericEditField
        LqEditFieldLabel                matlab.ui.control.Label
        LqEditField                     matlab.ui.control.NumericEditField
        FluxEditFieldLabel              matlab.ui.control.Label
        FluxEditField                   matlab.ui.control.NumericEditField
        Id_LimitEditFieldLabel          matlab.ui.control.Label
        Id_LimitEditField               matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        IdfTeTextAreaLabel              matlab.ui.control.Label
        IdfTeTextArea                   matlab.ui.control.TextArea
        DegreeofpolynomialLabel         matlab.ui.control.Label
        num_polyEditField               matlab.ui.control.NumericEditField
        MTPATableLabel                  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        PolepairsEditFieldLabel         matlab.ui.control.Label
        PolepairsEditField              matlab.ui.control.NumericEditField
        IqgTeTextAreaLabel              matlab.ui.control.Label
        IqgTeTextArea                   matlab.ui.control.TextArea
        IdLabel_2                       matlab.ui.control.Label
        IqLabel_2                       matlab.ui.control.Label
        TeLabel                         matlab.ui.control.Label
        TeLabel_2                       matlab.ui.control.Label
        IqLabel                         matlab.ui.control.Label
        IdLabel                         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           cla(app.UIAxes,'reset');
           cla(app.UIAxes2,'reset');
           cla(app.UIAxes2_2,'reset');
            %参数赋值
            Ld = app.LdEditField.Value;                  %Ld = get(app.LdEditField,'value');
            Lq = app.LqEditField.Value;                         %Lq = get(app.LqEditField,'value');
            psi_f = app.FluxEditField.Value;                         %psi_f =  get(app.FluxEditField,'value');
            id_limit = app.Id_LimitEditField.Value;                         %id_limit= get(app.Id_LimitEditField,'value');
            Ld_Lq=Ld-Lq;
            
            i_d=0:-2:id_limit;
            num_id=length(i_d);
                
            for k=1:1:num_id
               i_q(k)=sqrt((psi_f * i_d(k) + Ld_Lq*(i_d(k))^2)/Ld_Lq);
               
            end
            
            n_p=app.PolepairsEditField.Value;
            Te=1.5*n_p*i_q.*[psi_f+Ld_Lq*i_d];
            plot(app.UIAxes,i_d,i_q,'displayname','MTPA','linewidth',2);
            
            num_polynominal=app.num_polyEditField.Value;
            Coefficient_idTe=polyfit(Te,i_d,num_polynominal);
            Coefficient_iqTe=polyfit(Te,i_q,num_polynominal);
%             Coefficient_idiq=polyfit(id,iq,num_polynominal);
                        
            app.IdfTeTextArea.Value=num2str(Coefficient_idTe);  
            app.IqgTeTextArea.Value=num2str(Coefficient_iqTe);  
            
            i_d1=polyval(Coefficient_idTe,Te);
            i_q1=polyval(Coefficient_iqTe,Te);
            
%             绘制id=f(Te)的figure
            plot(app.UIAxes2,Te,i_d,'linewidth',2);
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,Te,i_d1,'linewidth',2);
%            绘制iq=g(Te)的figure
            plot(app.UIAxes2_2,Te,i_q,'linewidth',2);
            hold(app.UIAxes2_2,'on');
            plot(app.UIAxes2_2,Te,i_q1,'linewidth',2);
            
            
%             y=polyval(Coefficient,i_d);
%             hold(app.UIAxes,'on');
%             plot(app.UIAxes,i_d,y,'displayname','New','linewidth',2);
            legend(app.UIAxes);
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FittingMTPACoefficientUIFigure = uifigure('Visible', 'off');
            app.FittingMTPACoefficientUIFigure.Name = 'Fitting MTPA Coefficient';
            app.LdEditFieldLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.LdEditFieldLabel.Text = 'Ld';
            app.LdEditField = uieditfield(app.FittingMTPACoefficientUIFigure, 'numeric');
            app.LdEditField.Value = 0.00014;
            app.LqEditFieldLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.LqEditFieldLabel.Text = 'Lq';
            app.LqEditField = uieditfield(app.FittingMTPACoefficientUIFigure, 'numeric');
            app.LqEditField.Value = 0.0003115;
            app.FluxEditFieldLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.FluxEditFieldLabel.Text = 'Flux';
            app.FluxEditField = uieditfield(app.FittingMTPACoefficientUIFigure, 'numeric');
            app.FluxEditField.Value = 0.170559;
            app.Id_LimitEditFieldLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.Id_LimitEditFieldLabel.Text = 'Id_Limit';
            app.Id_LimitEditField = uieditfield(app.FittingMTPACoefficientUIFigure, 'numeric');
            app.Id_LimitEditField.Value = -400;
            app.StartButton = uibutton(app.FittingMTPACoefficientUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.IdfTeTextAreaLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.IdfTeTextAreaLabel.Text = 'Id=f(Te)';
            app.IdfTeTextArea = uitextarea(app.FittingMTPACoefficientUIFigure);
            app.DegreeofpolynomialLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.DegreeofpolynomialLabel.Text = 'Degree of polynomial';
            app.num_polyEditField = uieditfield(app.FittingMTPACoefficientUIFigure, 'numeric');
            app.num_polyEditField.Value = 3;
            app.MTPATableLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.MTPATableLabel.Text = 'MTPA Table';
            app.Label = uilabel(app.FittingMTPACoefficientUIFigure);
            app.Label.Text = '多项式次数';
            app.Label_2 = uilabel(app.FittingMTPACoefficientUIFigure);
            app.Label_2.Text = '多项式系数';
            app.PolepairsEditFieldLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.PolepairsEditFieldLabel.Text = 'Pole-pairs';
            app.PolepairsEditField = uieditfield(app.FittingMTPACoefficientUIFigure, 'numeric');
            app.PolepairsEditField.Value = 4;
            app.IqgTeTextAreaLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.IqgTeTextAreaLabel.Text = 'Iq=g(Te)';
            app.IqgTeTextArea = uitextarea(app.FittingMTPACoefficientUIFigure);
            app.IdLabel_2 = uilabel(app.FittingMTPACoefficientUIFigure);
            app.IdLabel_2.Text = 'Id';
            app.IqLabel_2 = uilabel(app.FittingMTPACoefficientUIFigure);
            app.IqLabel_2.Text = 'Iq';
            app.TeLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.TeLabel.Text = 'Te';
            app.TeLabel_2 = uilabel(app.FittingMTPACoefficientUIFigure);
            app.TeLabel_2.Text = 'Te';
            app.IqLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.IqLabel.Text = 'Iq';
            app.IdLabel = uilabel(app.FittingMTPACoefficientUIFigure);
            app.IdLabel.Text = 'Id';
            app.UIAxes = uiaxes(app.FittingMTPACoefficientUIFigure);
            title(app.UIAxes, 'i_d-i_q')
            xlabel(app.UIAxes, 'i_d')
            ylabel(app.UIAxes, 'i_q')
            app.UIAxes2 = uiaxes(app.FittingMTPACoefficientUIFigure);
            title(app.UIAxes2, 'T_e-i_d')
            xlabel(app.UIAxes2, 'T_e')
            ylabel(app.UIAxes2, 'i_d')
            app.UIAxes2_2 = uiaxes(app.FittingMTPACoefficientUIFigure);
            title(app.UIAxes2_2, 'T_e-i_q')
            xlabel(app.UIAxes2_2, 'T_e')
            ylabel(app.UIAxes2_2, 'i_q')
            % Show the figure after all components are created
            app.FittingMTPACoefficientUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fitting_MTPA_Coefficient2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FittingMTPACoefficientUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FittingMTPACoefficientUIFigure)
        end
    end
end